classdef GMRoller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GMRollerUIFigure      matlab.ui.Figure
        OptionsMenu           matlab.ui.container.Menu
        ClearFieldsMenu       matlab.ui.container.Menu
        ClearLogMenu          matlab.ui.container.Menu
        LogRollsMenu          matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        SpecialPanel          matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        SpecialText           matlab.ui.control.TextArea
        RollSpecialButton     matlab.ui.control.Button
        SpecialDropDown       matlab.ui.control.DropDown
        LogPanel              matlab.ui.container.Panel
        GridLayout4           matlab.ui.container.GridLayout
        LogUITable            matlab.ui.control.Table
        StandardPanel         matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        RollAllButton         matlab.ui.control.Button
        ModifierSpinner       matlab.ui.control.Spinner
        ModifierSpinnerLabel  matlab.ui.control.Label
        Rolld100Button        matlab.ui.control.Button
        d100Spinner           matlab.ui.control.Spinner
        d100SpinnerLabel      matlab.ui.control.Label
        Rolld20Button         matlab.ui.control.Button
        d20Spinner            matlab.ui.control.Spinner
        d20SpinnerLabel       matlab.ui.control.Label
        Rolld12Button         matlab.ui.control.Button
        d12Spinner            matlab.ui.control.Spinner
        d12SpinnerLabel       matlab.ui.control.Label
        Rolld10Button         matlab.ui.control.Button
        d10Spinner            matlab.ui.control.Spinner
        d10SpinnerLabel       matlab.ui.control.Label
        Rolld8Button          matlab.ui.control.Button
        d8Spinner             matlab.ui.control.Spinner
        d8SpinnerLabel        matlab.ui.control.Label
        Rolld6Button          matlab.ui.control.Button
        d6Spinner             matlab.ui.control.Spinner
        d6SpinnerLabel        matlab.ui.control.Label
        Rolld4Button          matlab.ui.control.Button
        d4Spinner             matlab.ui.control.Spinner
        d4SpinnerLabel        matlab.ui.control.Label
        Rolld2Button          matlab.ui.control.Button
        d2Spinner             matlab.ui.control.Spinner
        d2SpinnerLabel        matlab.ui.control.Label
        LogContextMenu        matlab.ui.container.ContextMenu
        ClearMenu             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        D2 = NumericDie(1:2)
        D4 = NumericDie(1:4)
        D6 = NumericDie(1:6)
        D8 = NumericDie(1:8)
        D10 = NumericDie(1:10)
        D12 = NumericDie(1:12)
        D20 = NumericDie(1:20)
        D100 = NumericDie(1:100)
        SpecialDice (:,2)
    end
    
    methods (Access = private)
        function results = rollStandardDice(app, name)
            %NOTE: Probably could write this more succinctly...
            mod = app.ModifierSpinner.Value;
            switch (name)
                case "d2"
                    times = app.d2Spinner.Value;
                    if times ~= 0
                        results = app.D2.roll(times, false);
                        total = sum(results) + mod;
                        app.LogUITable.Data = [app.LogUITable.Data 
                            total string(mod) string(times)+"d2"];
                    end
                case "d4"
                    times = app.d4Spinner.Value;
                    if times ~= 0
                        results = app.D4.roll(times, false);
                        total = sum(results) + mod;
                        app.LogUITable.Data = [app.LogUITable.Data 
                            total string(mod) string(times)+"d4"];
                    end
                case "d6"
                    times = app.d6Spinner.Value;
                    if times ~= 0
                        results = app.D6.roll(times, false);
                        total = sum(results) + mod;
                        app.LogUITable.Data = [app.LogUITable.Data
                            total string(mod) string(times)+"d6"];
                    end
                case "d8"
                    times = app.d8Spinner.Value;
                    if times ~= 0
                        results = app.D8.roll(times, false);
                        total = sum(results) + mod;
                        app.LogUITable.Data = [app.LogUITable.Data
                            total string(mod) string(times)+"d8"];
                    end
                case "d10"
                    times = app.d10Spinner.Value;
                    if times ~= 0
                        results = app.D10.roll(times, false);
                        total = sum(results) + mod;
                        app.LogUITable.Data = [app.LogUITable.Data
                            total string(mod) string(times)+"d10"];
                    end
                case "d12"
                    times = app.d12Spinner.Value;
                    if times ~= 0
                        results = app.D12.roll(times, false);
                        total = sum(results) + mod;
                        app.LogUITable.Data = [app.LogUITable.Data
                            total string(mod) string(times)+"d12"];
                    end
                case "d20"
                    times = app.d20Spinner.Value;
                    if times ~= 0
                        results = app.D20.roll(times, false);
                        total = sum(results) + mod;
                        app.LogUITable.Data = [app.LogUITable.Data
                            total string(mod) string(times)+"d20"];
                    end
                case "d100"
                    times = app.d100Spinner.Value;
                    if times ~= 0
                        results = app.D100.roll(times, false);
                        total = sum(results) + mod;
                        app.LogUITable.Data = [app.LogUITable.Data
                            total string(mod) string(times)+"d100"];
                    end
                case "all"
                    timesd2 = app.d2Spinner.Value;
                    dicestrings = [];
                    if timesd2 ~= 0
                        results = app.D2.roll(timesd2, false);
                        total = sum(results) + mod;
                        dicestrings = [dicestrings string(timesd2)+"d2"];
                    end
                    timesd4 = app.d4Spinner.Value;
                    if timesd4 ~= 0
                        results = app.D4.roll(timesd4, false);
                        total = sum(results) + mod;
                        dicestrings = [dicestrings string(timesd4)+"d4"];
                    end
                    timesd6 = app.d6Spinner.Value;
                    if timesd6 ~= 0
                        results = app.D6.roll(timesd6, false);
                        total = sum(results) + mod;
                        dicestrings = [dicestrings string(timesd6)+"d6"];
                    end
                    timesd8 = app.d8Spinner.Value;
                    if timesd8 ~= 0
                        results = app.D8.roll(timesd8, false);
                        total = sum(results) + mod;
                        dicestrings = [dicestrings string(timesd8)+"d8"];
                    end
                    timesd10 = app.d10Spinner.Value;
                    if timesd10 ~= 0
                        results = app.D10.roll(timesd10, false);
                        total = sum(results) + mod;
                        dicestrings = [dicestrings string(timesd10)+"d10"];
                    end
                    timesd12 = app.d12Spinner.Value;
                    if timesd12 ~= 0
                        results = app.D12.roll(timesd12, false);
                        total = sum(results) + mod;
                        dicestrings = [dicestrings string(timesd12)+"d12"];
                    end
                    timesd20 = app.d20Spinner.Value;
                    if timesd20 ~= 0
                        results = app.D20.roll(timesd20, false);
                        total = sum(results) + mod;
                        dicestrings = [dicestrings string(timesd20)+"d20"];
                    end
                    timesd100 = app.d100Spinner.Value;
                    if timesd100 ~= 0
                        results = app.D100.roll(timesd100, false);
                        total = sum(results) + mod;
                        dicestrings = [dicestrings string(timesd100)+"d100"];
                    end

                    app.LogUITable.Data = [app.LogUITable.Data
                            total string(mod) strjoin(dicestrings, ", ")];
            end
        end

        function updateSpecialString(app, die)
            sides = die.Sides;
            app.SpecialText.Value = strjoin(string(sides), ", ");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SpecialDice = {
                "Weather", CategoricDie(["Clear" "Clouds" "Rain" "Snow"]);
                "Direction", CategoricDie(["North" "East" "South" "West"]);
                "Mood", CategoricDie(["Happy" "Sad" "Angry" "Excited" "Anxious"]);
                };

            app.SpecialDropDown.Items = [app.SpecialDice{:,1}];
            app.updateSpecialString(app.SpecialDice{1,2});

            style = uistyle("HorizontalAlignment", "center");
            app.LogUITable.addStyle(style, "column", [1 2]);
        end

        % Menu selected function: ClearFieldsMenu
        function ClearFieldsMenuSelected(app, event)
            app.d2Spinner.Value = 0;
            app.d4Spinner.Value = 0;
            app.d6Spinner.Value = 0;
            app.d8Spinner.Value = 0;
            app.d10Spinner.Value = 0;
            app.d12Spinner.Value = 0;
            app.d20Spinner.Value = 0;
            app.d100Spinner.Value = 0;
            app.ModifierSpinner.Value = 0;
        end

        % Callback function: ClearLogMenu, LogContextMenu
        function ClearLogMenuSelected(app, event)
            app.LogUITable.Data = {};
        end

        % Button pushed function: Rolld2Button
        function Rolld2ButtonPushed(app, event)
            app.rollStandardDice("d2");
        end

        % Button pushed function: Rolld4Button
        function Rolld4ButtonPushed(app, event)
            app.rollStandardDice("d4");
        end

        % Button pushed function: Rolld6Button
        function Rolld6ButtonPushed(app, event)
            app.rollStandardDice("d6");
        end

        % Button pushed function: Rolld8Button
        function Rolld8ButtonPushed(app, event)
            app.rollStandardDice("d8");
        end

        % Button pushed function: Rolld10Button
        function Rolld10ButtonPushed(app, event)
            app.rollStandardDice("d10");
        end

        % Button pushed function: Rolld12Button
        function Rolld12ButtonPushed(app, event)
            app.rollStandardDice("d12");
        end

        % Button pushed function: Rolld20Button
        function Rolld20ButtonPushed(app, event)
            app.rollStandardDice("d20");
        end

        % Button pushed function: Rolld100Button
        function Rolld100ButtonPushed(app, event)
            app.rollStandardDice("d100");
        end

        % Button pushed function: RollAllButton
        function RollAllButtonPushed(app, event)
            app.rollStandardDice("all");
        end

        % Value changed function: SpecialDropDown
        function SpecialDropDownValueChanged(app, event)
            i = app.SpecialDropDown.ValueIndex;
            die = app.SpecialDice{i,2}; 
            app.updateSpecialString(die);
        end

        % Button pushed function: RollSpecialButton
        function RollSpecialButtonPushed(app, event)
            i = app.SpecialDropDown.ValueIndex;
            die = app.SpecialDice{i,2};
            result = die.roll(1, false);
            app.LogUITable.Data = [app.LogUITable.Data
                result NaN app.SpecialDice{i,1}];
        end

        % Menu selected function: LogRollsMenu
        function LogRollsMenuSelected(app, event)
            app.LogRollsMenu.Checked = ~app.LogRollsMenu.Checked;
            app.LogUITable.Data = app.LogUITable.Data(end,:);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GMRollerUIFigure = uifigure('Visible', 'off');
            app.GMRollerUIFigure.Name = 'GMRoller';
            app.OptionsMenu = uimenu(app.GMRollerUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.ClearFieldsMenu = uimenu(app.OptionsMenu);
            app.ClearFieldsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearFieldsMenuSelected, true);
            app.ClearFieldsMenu.Text = 'Clear Fields';
            app.ClearLogMenu = uimenu(app.OptionsMenu);
            app.ClearLogMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearLogMenuSelected, true);
            app.ClearLogMenu.Text = 'Clear Log';
            app.LogRollsMenu = uimenu(app.OptionsMenu);
            app.LogRollsMenu.MenuSelectedFcn = createCallbackFcn(app, @LogRollsMenuSelected, true);
            app.LogRollsMenu.Checked = 'on';
            app.LogRollsMenu.Text = 'Log Rolls';
            app.GridLayout = uigridlayout(app.GMRollerUIFigure);
            app.StandardPanel = uipanel(app.GridLayout);
            app.StandardPanel.Title = 'Standard';
            app.GridLayout2 = uigridlayout(app.StandardPanel);
            app.d2SpinnerLabel = uilabel(app.GridLayout2);
            app.d2SpinnerLabel.Text = 'd2';
            app.d2Spinner = uispinner(app.GridLayout2);
            app.d2Spinner.Limits = [0 Inf];
            app.d2Spinner.RoundFractionalValues = 'on';
            app.d2Spinner.ValueDisplayFormat = '%.0f';
            app.Rolld2Button = uibutton(app.GridLayout2, 'push');
            app.Rolld2Button.ButtonPushedFcn = createCallbackFcn(app, @Rolld2ButtonPushed, true);
            app.Rolld2Button.Text = 'Roll';
            app.d4SpinnerLabel = uilabel(app.GridLayout2);
            app.d4SpinnerLabel.Text = 'd4';
            app.d4Spinner = uispinner(app.GridLayout2);
            app.d4Spinner.Limits = [0 Inf];
            app.d4Spinner.RoundFractionalValues = 'on';
            app.d4Spinner.ValueDisplayFormat = '%.0f';
            app.Rolld4Button = uibutton(app.GridLayout2, 'push');
            app.Rolld4Button.ButtonPushedFcn = createCallbackFcn(app, @Rolld4ButtonPushed, true);
            app.Rolld4Button.Text = 'Roll';
            app.d6SpinnerLabel = uilabel(app.GridLayout2);
            app.d6SpinnerLabel.Text = 'd6';
            app.d6Spinner = uispinner(app.GridLayout2);
            app.d6Spinner.Limits = [0 Inf];
            app.d6Spinner.RoundFractionalValues = 'on';
            app.d6Spinner.ValueDisplayFormat = '%.0f';
            app.Rolld6Button = uibutton(app.GridLayout2, 'push');
            app.Rolld6Button.ButtonPushedFcn = createCallbackFcn(app, @Rolld6ButtonPushed, true);
            app.Rolld6Button.Text = 'Roll';
            app.d8SpinnerLabel = uilabel(app.GridLayout2);
            app.d8SpinnerLabel.Text = 'd8';
            app.d8Spinner = uispinner(app.GridLayout2);
            app.d8Spinner.Limits = [0 Inf];
            app.d8Spinner.RoundFractionalValues = 'on';
            app.d8Spinner.ValueDisplayFormat = '%.0f';
            app.Rolld8Button = uibutton(app.GridLayout2, 'push');
            app.Rolld8Button.ButtonPushedFcn = createCallbackFcn(app, @Rolld8ButtonPushed, true);
            app.Rolld8Button.Text = 'Roll';
            app.d10SpinnerLabel = uilabel(app.GridLayout2);
            app.d10SpinnerLabel.Text = 'd10';
            app.d10Spinner = uispinner(app.GridLayout2);
            app.d10Spinner.Limits = [0 Inf];
            app.d10Spinner.RoundFractionalValues = 'on';
            app.d10Spinner.ValueDisplayFormat = '%.0f';
            app.Rolld10Button = uibutton(app.GridLayout2, 'push');
            app.Rolld10Button.ButtonPushedFcn = createCallbackFcn(app, @Rolld10ButtonPushed, true);
            app.Rolld10Button.Text = 'Roll';
            app.d12SpinnerLabel = uilabel(app.GridLayout2);
            app.d12SpinnerLabel.Text = 'd12';
            app.d12Spinner = uispinner(app.GridLayout2);
            app.d12Spinner.Limits = [0 Inf];
            app.d12Spinner.RoundFractionalValues = 'on';
            app.d12Spinner.ValueDisplayFormat = '%.0f';
            app.Rolld12Button = uibutton(app.GridLayout2, 'push');
            app.Rolld12Button.ButtonPushedFcn = createCallbackFcn(app, @Rolld12ButtonPushed, true);
            app.Rolld12Button.Text = 'Roll';
            app.d20SpinnerLabel = uilabel(app.GridLayout2);
            app.d20SpinnerLabel.Text = 'd20';
            app.d20Spinner = uispinner(app.GridLayout2);
            app.d20Spinner.Limits = [0 Inf];
            app.d20Spinner.RoundFractionalValues = 'on';
            app.d20Spinner.ValueDisplayFormat = '%.0f';
            app.Rolld20Button = uibutton(app.GridLayout2, 'push');
            app.Rolld20Button.ButtonPushedFcn = createCallbackFcn(app, @Rolld20ButtonPushed, true);
            app.Rolld20Button.Text = 'Roll';
            app.d100SpinnerLabel = uilabel(app.GridLayout2);
            app.d100SpinnerLabel.Text = 'd100';
            app.d100Spinner = uispinner(app.GridLayout2);
            app.d100Spinner.Limits = [0 Inf];
            app.d100Spinner.RoundFractionalValues = 'on';
            app.d100Spinner.ValueDisplayFormat = '%.0f';
            app.Rolld100Button = uibutton(app.GridLayout2, 'push');
            app.Rolld100Button.ButtonPushedFcn = createCallbackFcn(app, @Rolld100ButtonPushed, true);
            app.Rolld100Button.Text = 'Roll';
            app.ModifierSpinnerLabel = uilabel(app.GridLayout2);
            app.ModifierSpinnerLabel.Text = 'Modifier';
            app.ModifierSpinner = uispinner(app.GridLayout2);
            app.ModifierSpinner.RoundFractionalValues = 'on';
            app.ModifierSpinner.ValueDisplayFormat = '%.0f';
            app.RollAllButton = uibutton(app.GridLayout2, 'push');
            app.RollAllButton.ButtonPushedFcn = createCallbackFcn(app, @RollAllButtonPushed, true);
            app.RollAllButton.Text = 'Roll All';
            app.LogPanel = uipanel(app.GridLayout);
            app.LogPanel.Title = 'Log';
            app.GridLayout4 = uigridlayout(app.LogPanel);
            app.LogUITable = uitable(app.GridLayout4);
            app.LogUITable.ColumnName = {'Result'; '+/-'; 'Dice'};
            app.LogUITable.RowName = {};
            app.LogUITable.SelectionType = 'row';
            app.LogUITable.Multiselect = 'off';
            app.SpecialPanel = uipanel(app.GridLayout);
            app.SpecialPanel.Title = 'Special';
            app.GridLayout3 = uigridlayout(app.SpecialPanel);
            app.SpecialDropDown = uidropdown(app.GridLayout3);
            app.SpecialDropDown.Items = {};
            app.SpecialDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecialDropDownValueChanged, true);
            app.SpecialDropDown.Value = {};
            app.RollSpecialButton = uibutton(app.GridLayout3, 'push');
            app.RollSpecialButton.ButtonPushedFcn = createCallbackFcn(app, @RollSpecialButtonPushed, true);
            app.RollSpecialButton.Text = 'Roll';
            app.SpecialText = uitextarea(app.GridLayout3);
            app.SpecialText.Editable = 'off';
            app.LogContextMenu = uicontextmenu(app.GMRollerUIFigure);
            app.LogContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ClearLogMenuSelected, true);
            app.ClearMenu = uimenu(app.LogContextMenu);
            app.ClearMenu.Text = 'Clear';
            % Assign app.LogContextMenu
            app.LogUITable.ContextMenu = app.LogContextMenu;
            % Show the figure after all components are created
            app.GMRollerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GMRoller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GMRollerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GMRollerUIFigure)
        end
    end
end